classdef ES1A < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExitButton                      matlab.ui.control.Button
        Entropy_text                    matlab.ui.control.EditField
        Range_of_n_text                 matlab.ui.control.EditField
        Distribution_text               matlab.ui.control.EditField
        EntropyLabel                    matlab.ui.control.Label
        RangeofnLabel                   matlab.ui.control.Label
        DistributionLabel               matlab.ui.control.Label
        DiscreteentropycalculatorLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function H = calculateEntropy(~ , probability_values)
            % Normalize the input vector
            total = sum(probability_values);
            for idx = (1:width(probability_values))
                probability_values(idx) = ...
                    probability_values(idx)/total;
            end
            % Applies the formula for entropy calculation
            H = 0;
            for p = probability_values
                H = H - p * log2(p);
            end
        end
        
        
        % Function that update the result is written only once
        % to be called by all relevant callbacks functions

        function updateResult(app, ~)
            input_string = app.Distribution_text.Value;
            % Get the distribution and handle errors
            distribution_string = strcat("@(n)(",input_string,")");
            try
                distribution = str2func(distribution_string);
            catch
                app.Entropy_text.Value = "Stringa funzionale errata";
                return
            end
            % Get the range and handle errors
            rangeString = app.Range_of_n_text.Value;
            try
                Range = split(rangeString, ",");
                Low = str2double(Range(1));
                High = str2double(Range(2));
            catch
                app.Entropy_text.Value = "Range non valido";
                return
            end
            % Applying the distribution to an evenly spaced array
            N = (Low:1:High);
            PN = arrayfun(distribution, N);
            % Plot the values on the graph
            plot(app.UIAxes, N, PN/sum(PN), "-o");
            % Calculate the entropy and show it
            H = calculateEntropy(app, PN);
            app.Entropy_text.Value = sprintf("%.6f", H);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function intialization(app)
            app.Distribution_text.Value = "1/(n^2+1)";
            app.Range_of_n_text.Value = "0,10";
            updateResult(app);
        end

        % Value changed function: Distribution_text
        function Distribution_textValueChanged(app, event)
            % It just calls the function that updates the result
            updateResult(app, event)
        end

        % Value changed function: Range_of_n_text
        function Range_of_n_textValueChanged(app, event)
            % It just calls the function that updates the result
            updateResult(app, event)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Closes the app
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'p(n)')
            app.DiscreteentropycalculatorLabel = uilabel(app.UIFigure);
            app.DiscreteentropycalculatorLabel.Text = 'Discrete entropy calculator';
            app.DistributionLabel = uilabel(app.UIFigure);
            app.DistributionLabel.Text = 'Distribution';
            app.RangeofnLabel = uilabel(app.UIFigure);
            app.RangeofnLabel.Text = 'Range of n';
            app.EntropyLabel = uilabel(app.UIFigure);
            app.EntropyLabel.Text = 'Entropy';
            app.Distribution_text = uieditfield(app.UIFigure, 'text');
            app.Distribution_text.ValueChangedFcn = createCallbackFcn(app, @Distribution_textValueChanged, true);
            app.Distribution_text.Tag = 'Distribution';
            app.Range_of_n_text = uieditfield(app.UIFigure, 'text');
            app.Range_of_n_text.ValueChangedFcn = createCallbackFcn(app, @Range_of_n_textValueChanged, true);
            app.Entropy_text = uieditfield(app.UIFigure, 'text');
            app.Entropy_text.Editable = 'off';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ES1A

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @intialization)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end