classdef ex2_a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        p_infinityLabel    matlab.ui.control.Label
        RECALCULATEButton  matlab.ui.control.Button
        EXITButton         matlab.ui.control.Button
        Calculationofp_infinityfromPLabel  matlab.ui.control.Label
        ProbabilityMatrix  matlab.ui.control.TextArea
        ProbabilitymatrixPtobenormalizedTextAreaLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RECALCULATEButton
        function CalculatePInfinity(app, event)
            % Read transition probability matrix
            inputText = app.ProbabilityMatrix.Value;
            
            inputText = strrep(inputText, '[', ''); % Remove '['
            inputText = strrep(inputText, ']', ''); % Remove ']'

            % Parse the input in a numerical matrix
            rows = split(inputText, ';');
            P = zeros(length(rows), length(rows));
            for i = 1:length(rows)
                % Convert row in numeric array
                rowValues = str2double(split(rows{i}, ' '));
                
                % Remove NaN from the row
                rowValues(isnan(rowValues)) = [];
                
                % Check for negative values
                if any(rowValues < 0)
                    uialert(app.UIFigure, 'The probabilities must be non-negative', 'Error');
                    return;
                end

                % Add row to the matrix if not null
                if length(rowValues') == length(P(i, :))
                    P(i, :) = rowValues';
                else
                    uialert(app.UIFigure, 'The matrix must be a square matrix', 'Error');
                    return;
                end
            end
            
            % Check if the matrix is square
            [m, n] = size(P);
            if m ~= n
                uialert(app.UIFigure, 'The matrix must be a square matrix', 'Error');
                return;
            end

            % Normalize the matrix
            rowSums = sum(P, 2);
            P = P ./ rowSums;
            
            % Calculate stationary state probability: p_infinity
            A = eye(n) - P';
            A(1, :) = ones(1, n);
            b = [1; zeros(n-1, 1)];
            p_inf = A \ b;
            
            % Convert p_inf in string
            p_inf_str = sprintf('%.5f, ', p_inf');
            p_inf_str(end-1:end) = []; % Remove last comma
            
            % Add parenthesis
            p_inf_str = ['p_infinity = (' p_inf_str ')'];
            
            % Show p_inf in the Label
            app.p_infinityLabel.Text = p_inf_str;
        end

        % Button pushed function: EXITButton
        function ExitPushed(app, event)

            % Close the app
            delete(app.UIFigure);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProbabilitymatrixPtobenormalizedTextAreaLabel = uilabel(app.UIFigure);
            app.ProbabilitymatrixPtobenormalizedTextAreaLabel.Text = 'Probability matrix P (to be normalized)';
            app.ProbabilityMatrix = uitextarea(app.UIFigure);
            app.ProbabilityMatrix.Value = {'[1 3;2 8]'};
            app.Calculationofp_infinityfromPLabel = uilabel(app.UIFigure);
            app.Calculationofp_infinityfromPLabel.Text = 'Calculation of p_infinity  from P';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @ExitPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.RECALCULATEButton = uibutton(app.UIFigure, 'push');
            app.RECALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePInfinity, true);
            app.RECALCULATEButton.Text = 'RECALCULATE';
            app.p_infinityLabel = uilabel(app.UIFigure);
            app.p_infinityLabel.Text = 'p_infinity =';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ex2_a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end