classdef ex3_b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RecalculateButton          matlab.ui.control.Button
        TimeLabel                  matlab.ui.control.Label
        ExitButton                 matlab.ui.control.Button
        DistortionLabel            matlab.ui.control.Label
        IterationsEditField        matlab.ui.control.NumericEditField
        GlobaloptimizationiterationsEditFieldLabel  matlab.ui.control.Label
        SeedEditField              matlab.ui.control.NumericEditField
        SeedEditFieldLabel         matlab.ui.control.Label
        QuantizationPointsSpinner  matlab.ui.control.Spinner
        NumberofquantizationpointsSpinnerLabel  matlab.ui.control.Label
        RangeEditField             matlab.ui.control.EditField
        RangeofxyLabel             matlab.ui.control.Label
        ResolutionEditField        matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel   matlab.ui.control.Label
        PDFEditField               matlab.ui.control.EditField
        PDFtobenormalizedEditFieldLabel  matlab.ui.control.Label
        LBGalgorithmLabel          matlab.ui.control.Label
        CentroidAxes               matlab.ui.control.UIAxes
        DistortionAxes             matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function D = custom_pdist2(app, X, Y)
            %Pairwise distance computation between two sets of points
            %
            % Inputs:
            %   - X: A matrix of size m-by-n (m points in n-dimensional space)
            %   - Y: A matrix of size p-by-n (p points in n-dimensional space)
            %
            % Output:
            %   - D: A matrix of size m-by-p, where D(i, j) is the euclidean distance between X(i, :) and Y(j, :)

            % Calculate squared Euclidean distances using broadcasting
            X_squared = sum(X.^2, 2); % m-by-1
            Y_squared = sum(Y.^2, 2); % p-by-1
            cross_term = X * Y';      % m-by-p matrix of dot products
        
            % Compute squared distances directly
            D_squared = X_squared + Y_squared' - 2 * cross_term;
        
            % Ensure non-negative values (for numerical stability)
            D_squared(D_squared < 0) = 0;
        
            % Return the final Euclidean distances
            D = sqrt(D_squared);
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecalculateButton
        function LBGalg(app, event)
            
            function [centroids, distortion] = singleLBG_run(X, Y, Z, q, M)
                % Flatten grid for centroid initialization
                grid_points = [X(:), Y(:)];
                
                % Random initial centroids
                rand_indices = randperm(size(grid_points, 1), q);
                centroids = grid_points(rand_indices, :);

                distortion_old = 0;
                      
                % Iterate up to M times to update centroids based on Voronoi regions
                for iter = 1:M

                    % Inizialize distortion
                    distortion = 0;

                    % Initialize array for new centroids
                    new_centroids = zeros(q, 2);
                    
                    % Computation of the Voronoi regions
                    distances = custom_pdist2(app, grid_points, centroids);
                    [~, region_indices] = min(distances, [], 2);

                    for region = 1:q
                        % Find the points belonging to the current region
                        region_mask = (region_indices == region);
                        region_points = grid_points(region_mask, :);
                        region_weights = Z(region_mask);
                        
                        % Calculate distorion
                        
                        % Compute squared distance for points in the region
                        squared_distances = sum((region_points - centroids(region, :)).^2, 2);

                        % Compute square error distortion
                        if iter == 1
                            distortion_old = distortion_old + sum(region_weights .* squared_distances);
                        else
                            distortion = distortion + sum(region_weights .* squared_distances);
                        end

                        % Compute the new centroid for the current region
                        x_c = sum(region_points(:, 1) .* region_weights) / sum(region_weights);
                        y_c = sum(region_points(:, 2) .* region_weights) / sum(region_weights);
                        new_centroids(region, :) = [x_c, y_c];
                    end

                    % Check for cenvergence
                    if iter ~= 1
                        if ((distortion_old - distortion) / distortion_old) < 1e-4
                            break;
                        end
                        distortion_old = distortion;
                    end

                    % Update the centroids
                    centroids = new_centroids;
                end
            end

            % Read probability density function and format to MATLAB
            % standard
            pdf_str = app.PDFEditField.Value;
            pdf_str = strrep(pdf_str, '^', '.^');
            pdf_str = strrep(pdf_str, '*', '.*');
            pdf_str = strrep(pdf_str, '/', './');
            try
                pdf_func = str2func(['@(x, y)', pdf_str]);
            catch ME
                uialert(app.UIFigure, 'Invalid probability function', 'Error');
                return;
            end

            % Read ranges for x and y and check correctness
            ranges_str = app.RangeEditField.Value;
            ranges = str2double(split(ranges_str, ' '));
            ranges(isnan(ranges)) = []; % Remove NaN from the ranges
            if length(ranges) ~= 4 || any(isnan(ranges)) 
                uialert(app.UIFigure, 'Erroneous ranges', 'Error');
                return;
            end
            x0 = ranges(1);
            xN = ranges(2);
            y0 = ranges(3);
            yN = ranges(4);
            if x0 >= xN || y0 >= yN
                uialert(app.UIFigure, 'Erroneous ranges', 'Error');
                return;
            end

            % Read the number of quantization points and check correctness
            q = app.QuantizationPointsSpinner.Value;
            if q <= 0
               uialert(app.UIFigure, 'Invalid number of quantization points', 'Error');
                return;
            end

            % Read the resolution and check correctness
            N = app.ResolutionEditField.Value;
            if N <= 0 || N^2 < q
                uialert(app.UIFigure, 'Invalid resolution', 'Error');
                return;
            end

            % Read the seed and check correctness
            seed = app.SeedEditField.Value;
            if seed < 0
               uialert(app.UIFigure, 'Invalid seed', 'Error');
                return;
            end

            % Read the number of global optimization iterations and check
            % correcteness
            R = app.IterationsEditField.Value;
            if R <= 0
               uialert(app.UIFigure, 'Invalid number of iterations', 'Error');
                return;
            end
            
            % Maximum number of iterations for LBG algorithm
            M = 100;

            x = linspace(x0, xN, N);
            y = linspace(y0, yN, N);
            [X, Y] = meshgrid(x, y); % Grid of mass points
            Z = pdf_func(X, Y); % Calculate PDF over the mass points
            Z = Z / sum(Z(:));  % Normalize PDF over the grid
            Z = Z(:); % Z becomes a vector for easier manipulation
            
            rng(seed); % Set the seed

            % Initialize array for optimum centroids
            best_distortion = inf;
            best_centroids = [];
        
            % Initialize array to track distortion progression across runs
            distortion_evolution = zeros(R, 1);
        
            % Start timer
            tic;

            % Perform R times the LBG algorithm with different
            % initializations
            for run = 1:R
                % Run LBG with this initialization
                [centroids, final_distortion] = singleLBG_run(X, Y, Z, q, M);
        
                % Update distortion evolution for plotting
                if run == 1
                    distortion_evolution(run) = final_distortion;
                else
                    distortion_evolution(run) = min(distortion_evolution(run-1), final_distortion);
                end
        
                % Check if this run achieved a new best distortion
                if final_distortion < best_distortion
                    best_distortion = final_distortion;
                    best_centroids = centroids;
                end
            end

            % Measure elapsed time
            elapsedTime = toc;

            % Update label with computation time
            app.TimeLabel.Text = sprintf('Running time: %.5f s', elapsedTime);
            
            % Plot distortion evolution
            plot(app.DistortionAxes, 1:R, distortion_evolution, '-o', 'LineWidth', 1, ...
                 'MarkerSize', 6);
            title(app.DistortionAxes, 'Distortion Evolution Over Iterations');
        
            % Plot the best centroids on the grid
            plot(app.CentroidAxes, best_centroids(:, 1), best_centroids(:, 2), 'x', 'Color', 'blue', ...
                 'MarkerSize', 8, 'LineWidth', 1);
            title(app.CentroidAxes, 'Best Centroids');
            hold(app.CentroidAxes, 'off');

            % Update label with optimum distortion
            app.DistortionLabel.Text = sprintf('Optimum distortion (%d trials) = %.6f', R, distortion_evolution(end));
        end

        % Button pushed function: ExitButton
        function Exit(app, event)
            
            % Close the app
            delete(app.UIFigure);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DistortionAxes = uiaxes(app.UIFigure);
            title(app.DistortionAxes, 'Title')
            xlabel(app.DistortionAxes, 'Trial number')
            ylabel(app.DistortionAxes, 'Distortion')
            zlabel(app.DistortionAxes, 'Z')
            app.CentroidAxes = uiaxes(app.UIFigure);
            title(app.CentroidAxes, 'Title')
            xlabel(app.CentroidAxes, 'X')
            ylabel(app.CentroidAxes, 'Y')
            zlabel(app.CentroidAxes, 'Z')
            app.LBGalgorithmLabel = uilabel(app.UIFigure);
            app.LBGalgorithmLabel.Text = 'LBG algorithm';
            app.PDFtobenormalizedEditFieldLabel = uilabel(app.UIFigure);
            app.PDFtobenormalizedEditFieldLabel.Text = 'PDF (to be normalized)';
            app.PDFEditField = uieditfield(app.UIFigure, 'text');
            app.PDFEditField.Value = 'exp(-(x^2+y^2)/2)';
            app.ResolutionEditFieldLabel = uilabel(app.UIFigure);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResolutionEditField.Value = 100;
            app.RangeofxyLabel = uilabel(app.UIFigure);
            app.RangeofxyLabel.Text = 'Range of (x,y)';
            app.RangeEditField = uieditfield(app.UIFigure, 'text');
            app.RangeEditField.Value = '-2 2 -2 2';
            app.NumberofquantizationpointsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofquantizationpointsSpinnerLabel.Text = 'Number of quantization points';
            app.QuantizationPointsSpinner = uispinner(app.UIFigure);
            app.QuantizationPointsSpinner.Value = 7;
            app.SeedEditFieldLabel = uilabel(app.UIFigure);
            app.SeedEditFieldLabel.Text = 'Seed';
            app.SeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.GlobaloptimizationiterationsEditFieldLabel = uilabel(app.UIFigure);
            app.GlobaloptimizationiterationsEditFieldLabel.Text = 'Global optimization iterations';
            app.IterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationsEditField.Value = 10;
            app.DistortionLabel = uilabel(app.UIFigure);
            app.DistortionLabel.Text = 'Optimal distortion (N trials) =';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @Exit, true);
            app.ExitButton.Text = 'Exit';
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = 'Running time:';
            app.RecalculateButton = uibutton(app.UIFigure, 'push');
            app.RecalculateButton.ButtonPushedFcn = createCallbackFcn(app, @LBGalg, true);
            app.RecalculateButton.Text = 'Recalculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ex3_b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end