classdef ex3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ProbabilityTable                matlab.ui.control.Table
        RECALCULATEButton               matlab.ui.control.Button
        EXITButton                      matlab.ui.control.Button
        HuffmanrateLabel                matlab.ui.control.Label
        EntropyrateLabel                matlab.ui.control.Label
        OrderSpinner                    matlab.ui.control.Spinner
        GroupingorderSpinnerLabel       matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TextTextAreaLabel               matlab.ui.control.Label
        TextentropyratecalculatorLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RECALCULATEButton
        function CalculateButton(app, event)
            % Join the text in a single string
            text = strjoin(app.TextArea.Value);

            % Get the symbol length from the spinner
            tokenLength = app.OrderSpinner.Value;
            if tokenLength < 1
                uialert(app.UIFigure, 'The order must be at least 1', 'Error');
                return;
            end
            
            % 1. Divide the text in symbols
            numTokens = ceil(length(text) / tokenLength);
            tokens = strings(numTokens, 1);
            
            for i = 1:numTokens
                startIdx = (i - 1) * tokenLength + 1;
                endIdx = min(i * tokenLength, length(text));
                
                % Extract the symbol and eventually pad with spaces
                token = text(startIdx:endIdx);
                if length(token) < tokenLength
                    token = pad(token, tokenLength, 'right');
                end
                tokens(i) = token;
            end

            % 2. Compute the frequency for each symbol
            [uniqueTokens, ~, idx] = unique(tokens);
            numOccurrences = accumarray(idx, 1);
            probabilities = numOccurrences / sum(numOccurrences);
            
            % 3. Sort the symbol by decreasing probability
            [sortedProbs, sortIdx] = sort(probabilities, 'descend');
            sortedTokens = uniqueTokens(sortIdx);
            
            % 4. Show symbols and probabilities in the table
            
            % Format symbols and probabilities
            formattedProbabilities = strings(length(sortedProbs), 1);
            formattedTokens = strings(length(sortedTokens), 1);
            for i = 1:length(sortedProbs)
                formattedProbabilities{i} = sprintf('%.8f', sortedProbs(i));
            end
            for i = 1:length(sortedTokens)
                formattedTokens{i} = sprintf('"%s"', sortedTokens{i});
            end

            app.ProbabilityTable.Data = table(formattedTokens, formattedProbabilities);
            
            % 5. Compute the symbols entropy
            entropy = -sum(sortedProbs .* log2(sortedProbs));

            % 6. Compute the avg bits per symbol with Huffman algorithm
            avgLength = 0;

            % Iterate until there are no more symbols
            while length(probabilities) > 1
                % Find the indices of the smallest probabilities
                [~, idx] = sort(probabilities);
                p1 = probabilities(idx(1));
                p2 = probabilities(idx(2));
        
                % Combine the 2 probabilities
                newProb = p1 + p2;
        
                % Update the probability vector
                probabilities = [probabilities([idx(3:end)]); newProb];
        
                % Update the avg length
                avgLength = avgLength + newProb;
            end

            % 7. Compute entropy and avg length for single character
            entropy = entropy / tokenLength;
            avgLength = avgLength / tokenLength;

            % 8. Display the entropy and the avg bits per character
            app.EntropyrateLabel.Text = sprintf('Entropy rate = %.4f bits/character', entropy);
            app.HuffmanrateLabel.Text = sprintf('Huffman rate = %.4f bits/character', avgLength);
        end

        % Button pushed function: EXITButton
        function ExitPushed(app, event)
            
            % Close the app
            delete(app.UIFigure);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextentropyratecalculatorLabel = uilabel(app.UIFigure);
            app.TextentropyratecalculatorLabel.Text = 'Text entropy rate calculator';
            app.TextTextAreaLabel = uilabel(app.UIFigure);
            app.TextTextAreaLabel.Text = 'Text';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Sir Isaac Newton (1642-1727) was an English mathematician, physicist, astronomer, and key figure in the scientific revolution. Born in Woolsthorpe, LincolnShire, Newton is best known for formulating the laws of motion and universal gravitation, which laid the foundation for classical mechanics. His book *Philosophiæ Naturalis Principia Mathematica* (1687), often referred to as the *Principia*, is considered one of the most important works in the history of science.'; 'Newton also made significant contributions to mathematics, including the development of calculus (independently of Leibniz), and optics, where he studied the nature of light and color. He served as the president of the Royal Society and held a position as Master of the Royal Mint. Newton''s work transformed the understanding of the physical world and deeply influenced later scientific inquiry.'};
            app.GroupingorderSpinnerLabel = uilabel(app.UIFigure);
            app.GroupingorderSpinnerLabel.Text = 'Grouping order';
            app.OrderSpinner = uispinner(app.UIFigure);
            app.OrderSpinner.Value = 3;
            app.EntropyrateLabel = uilabel(app.UIFigure);
            app.EntropyrateLabel.Text = 'Entropy rate =';
            app.HuffmanrateLabel = uilabel(app.UIFigure);
            app.HuffmanrateLabel.Text = 'Huffman rate =';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @ExitPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.RECALCULATEButton = uibutton(app.UIFigure, 'push');
            app.RECALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton, true);
            app.RECALCULATEButton.Text = 'RECALCULATE';
            app.ProbabilityTable = uitable(app.UIFigure);
            app.ProbabilityTable.ColumnName = {'Word'; 'Probability'};
            app.ProbabilityTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ex3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end